classdef Controle_banc_pos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        InitialisationMenu   matlab.ui.container.Menu
        InitialisertousMenu  matlab.ui.container.Menu
        InitialiserXMenu     matlab.ui.container.Menu
        InitialiserYMenu     matlab.ui.container.Menu
        InitialiserZMenu     matlab.ui.container.Menu
        XApplyButton         matlab.ui.control.Button
        YApplyButton         matlab.ui.control.Button
        ZApplyButton         matlab.ui.control.Button
        AppliquertousButton  matlab.ui.control.Button
        XLabel               matlab.ui.control.Label
        YLabel               matlab.ui.control.Label
        ZLabel               matlab.ui.control.Label
        XSpinner             matlab.ui.control.Spinner
        YSpinner             matlab.ui.control.Spinner
        ZSpinner             matlab.ui.control.Spinner
        XaButton             matlab.ui.control.StateButton
        XbButton             matlab.ui.control.StateButton
        YaButton             matlab.ui.control.StateButton
        YbButton             matlab.ui.control.StateButton
        ZaButton             matlab.ui.control.StateButton
        ZbButton             matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        callingApp % Application principale
        boutonsXYZab % Description
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Sauver application principale
            app.callingApp = mainapp;
            
            % Liste des boutons
            app.boutonsXYZab = [app.XaButton app.XbButton; app.YaButton app.YbButton; app.ZaButton app.ZbButton]';
            
            % timer pour mettre à jour la position des axes
            app.timerPos = timer("TimerFcn", XYZabButtonValueChanged);
        end

        % Close request function: UIFigure
        function closeRequest(app, event)
            % Notifier l'application principale
            PositionAppClosed(app.callingApp)
            
            % Supprimer l'application
            delete(app)
        end

        % Value changed function: XaButton, XbButton, YaButton, 
        % YbButton, ZaButton, ZbButton
        function XYZabButtonValueChanged(app, event)
            [dir,axe] = find(event.Source == app.boutonsXYZab);
            
            if(event.Value)
                if ( dir>1 )
                    dir = 1;
                    if app.boutonsXYZab(1,axe).Value
                        app.boutonsXYZab(1,axe).Value = 0;
                        bras_arret(axe)
                    end
                else
                    dir = -1;
                    if app.boutonsXYZab(2,axe).Value
                        app.boutonsXYZab(2,axe).Value = 0;
                        bras_arret(axe)
                    end
                end
                
                switch axe
                    case 1
                        bras_deplacer( dir*inf, [], [] )
                    case 2
                        bras_deplacer( [], dir*inf, [] )
                    case 3
                        bras_deplacer( [], [], dir*inf )
                end
            else
                bras_arret(axe)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Contrôle de la position';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.InitialisationMenu = uimenu(app.UIFigure);
            app.InitialisationMenu.Text = 'Initialisation';
            app.InitialisertousMenu = uimenu(app.InitialisationMenu);
            app.InitialisertousMenu.Text = 'Initialiser tous';
            app.InitialiserXMenu = uimenu(app.InitialisationMenu);
            app.InitialiserXMenu.Text = 'Initialiser X';
            app.InitialiserYMenu = uimenu(app.InitialisationMenu);
            app.InitialiserYMenu.Text = 'Initialiser Y';
            app.InitialiserZMenu = uimenu(app.InitialisationMenu);
            app.InitialiserZMenu.Text = 'Initialiser Z';
            app.XApplyButton = uibutton(app.UIFigure, 'push');
            app.XApplyButton.Text = 'Appliquer';
            app.YApplyButton = uibutton(app.UIFigure, 'push');
            app.YApplyButton.Text = 'Appliquer';
            app.ZApplyButton = uibutton(app.UIFigure, 'push');
            app.ZApplyButton.Text = 'Appliquer';
            app.AppliquertousButton = uibutton(app.UIFigure, 'push');
            app.AppliquertousButton.Text = 'Appliquer tous';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X = 0.0 mm';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y = 0.0 mm';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z = 0.0 mm';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.ValueDisplayFormat = '%11.4g mm';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueDisplayFormat = '%11.4g mm';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.ValueDisplayFormat = '%11.4g mm';
            app.XaButton = uibutton(app.UIFigure, 'state');
            app.XaButton.ValueChangedFcn = createCallbackFcn(app, @XYZabButtonValueChanged, true);
            app.XaButton.Text = '+';
            app.XbButton = uibutton(app.UIFigure, 'state');
            app.XbButton.ValueChangedFcn = createCallbackFcn(app, @XYZabButtonValueChanged, true);
            app.XbButton.Text = '-';
            app.YaButton = uibutton(app.UIFigure, 'state');
            app.YaButton.ValueChangedFcn = createCallbackFcn(app, @XYZabButtonValueChanged, true);
            app.YaButton.Text = '+';
            app.YbButton = uibutton(app.UIFigure, 'state');
            app.YbButton.ValueChangedFcn = createCallbackFcn(app, @XYZabButtonValueChanged, true);
            app.YbButton.Text = '-';
            app.ZaButton = uibutton(app.UIFigure, 'state');
            app.ZaButton.ValueChangedFcn = createCallbackFcn(app, @XYZabButtonValueChanged, true);
            app.ZaButton.Text = '+';
            app.ZbButton = uibutton(app.UIFigure, 'state');
            app.ZbButton.ValueChangedFcn = createCallbackFcn(app, @XYZabButtonValueChanged, true);
            app.ZbButton.Text = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controle_banc_pos(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end